classdef ControlChart < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        CharttypeDropDown              matlab.ui.control.DropDown
        CharttypeDropDownLabel         matlab.ui.control.Label
        ProcessincontrolLamp           matlab.ui.control.Lamp
        ProcessincontrolLampLabel      matlab.ui.control.Label
        ManageDataPanel                matlab.ui.container.Panel
        isRealDataLoaded               matlab.ui.control.Lamp
        EditDataButton                 matlab.ui.control.Button
        LoadDataButton                 matlab.ui.control.Button
        CalibrationPanel               matlab.ui.container.Panel
        ShowVmaskatSpinner             matlab.ui.control.Spinner
        ShowVmaskatSpinnerLabel        matlab.ui.control.Label
        WarningLimitEditField          matlab.ui.control.NumericEditField
        ManualButton                   matlab.ui.control.StateButton
        AutomaticButton                matlab.ui.control.StateButton
        WarningLimitEditFieldLabel     matlab.ui.control.Label
        ControlLimitEditField          matlab.ui.control.NumericEditField
        ControlLimitEditFieldLabel     matlab.ui.control.Label
        DeviationEditField             matlab.ui.control.NumericEditField
        DeviationEditFieldLabel        matlab.ui.control.Label
        TargetValueEditField           matlab.ui.control.NumericEditField
        TargetValueLabel               matlab.ui.control.Label
        NumbercalibrationpointsEditField  matlab.ui.control.NumericEditField
        NumbercalibrationpointsEditFieldLabel  matlab.ui.control.Label
        StabilityAnalysisRulesPanel    matlab.ui.container.Panel
        Rule4Bias8points               matlab.ui.control.CheckBox
        Rule3Trend7points              matlab.ui.control.CheckBox
        Rule2TwoOutsideWL              matlab.ui.control.CheckBox
        Rule1OutsideControl            matlab.ui.control.CheckBox
        SimulateDataPanel              matlab.ui.container.Panel
        target_sd                      matlab.ui.control.NumericEditField
        target                         matlab.ui.control.NumericEditField
        HowmanycalibrationpointsEditFieldLabel_3  matlab.ui.control.Label
        number_of_samples              matlab.ui.control.NumericEditField
        HowmanycalibrationpointsEditFieldLabel_2  matlab.ui.control.Label
        GenerateDataButton             matlab.ui.control.Button
        isSimulatedDataGenerated       matlab.ui.control.Lamp
        IncalibrationCheckBox          matlab.ui.control.CheckBox
        OutliersEditField              matlab.ui.control.NumericEditField
        OutliersEditFieldLabel         matlab.ui.control.Label
        SensorVariationEditField       matlab.ui.control.NumericEditField
        SensorVariationEditFieldLabel  matlab.ui.control.Label
        ReferenceVariationEditField    matlab.ui.control.NumericEditField
        ReferenceVariationEditFieldLabel  matlab.ui.control.Label
        topplot                        matlab.ui.control.UIAxes
        bottomplot                     matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        reference_data=[]
        sensor_data=[]
        data_changed = false;
        data_type=[];
        calibration_mode = 'auto';
        yellow = [0.9, 0.9, 0.1];
        red = [0.8, 0.1, 0.1];
        green = [0.4, 0.7, 0.2];
        purple = [0.1, 0.1, 0.9];
        basecolor = [0.9, 0.9, 0.9];
    end
    
    methods (Access = private)
        
        function getSimulatedData(app, n_samples, target, target_var, ref_var, sensor_var, n_outliers, allow_outliers_in_calibration)
            %target = 5;
             [app.reference_data, app.sensor_data] = CalibSimulate(...
                 n_samples, target, target_var, ref_var, sensor_var, false);
             
             if n_outliers>0
                 sd = max([sensor_var, ref_var, target_var]);% var(app.sensor_data);
                 outliers = rand(n_outliers,1)*sd/3 + 5*sd;
                 outliers = sign(randn(n_outliers,1)) .* outliers + target; 
                 
                 if allow_outliers_in_calibration
                     idx_rand = randperm(n_samples);
                     n_ref = 0;
                 else
                     n_ref = app.NumbercalibrationpointsEditField.Value;
                     idx_rand = randperm(n_samples-n_ref)+n_ref;
                 end
                 app.sensor_data(idx_rand(1:min(n_outliers, n_samples-n_ref))) = outliers;
%                  app.sensor_data(n_samples+1:end) = []; % If we create to much sensor data
                 
             end
             
        end
        
        function updateFigure(app)
%             if app.data_changed
                if ~isempty(app.sensor_data)
                    
                    cla(app.topplot)
                    plotData(app.reference_data, app.sensor_data, app.topplot);%,...
%                         'Reference and Sensor Data',... % title
%                         'Observations',... % xlabel
%                         'Value'); % ylabel
                    
                    
                    app.controlChartChanged(app.bottomplot)
                    figure(app.UIFigure);
                end
               
                
%             end
            
        end
        
        function saveCurrentAppView(app, filename,screen_pos)
            % Calls JAVA to take a screenshot of the location the figure is
            % in. This should generally work, but might break if the user
            % switches between different resolutions without restarting the
            % app/matlab.

            figure(app.UIFigure); % Ensure the figure is on top.
            % Take screen capture
            robot = java.awt.Robot();
            %pos = [0 0 400 400]; % [left top width height]
            pos_org = app.UIFigure.Position;
            pos=pos_org;
            pos(2) = screen_pos(4)-pos_org(2)-pos_org(4);
            rect = java.awt.Rectangle(pos(1),pos(2),pos(3),pos(4));
            
            figure(app.UIFigure); % Ensure the figure is on top.
            cap = robot.createScreenCapture(rect);
            % Convert to an RGB image
            rgb = typecast(cap.getRGB(0,0,cap.getWidth,cap.getHeight,[],0,cap.getWidth),'uint8');
            imgData = ones(cap.getHeight,cap.getWidth,3,'uint8')*128;
            imgData(:,:,1) = reshape(rgb(3:4:end),cap.getWidth,[])';
            imgData(:,:,2) = reshape(rgb(2:4:end),cap.getWidth,[])';
            imgData(:,:,3) = reshape(rgb(1:4:end),cap.getWidth,[])';
            % Show or save to file
%             figure; imshow(imgData) % Shows the screenshot
            imwrite(imgData,filename)
            
        end
        
        function simulatedDataChanged(app)
            % Tracks if the setting of the simulated data changed and was
            % not regenerated.
            app.data_changed = true;
            app.isSimulatedDataGenerated.Color = app.red;
        end
        
        function controlChartChanged(app, fig_handle)
            % Plot control chart
            
            if ~isempty(app.sensor_data)
                app.CalibrationPanel.Visible = true;
                cla(fig_handle)
                
                % Get which rules are turned on
                apply_rules = [app.Rule1OutsideControl.Value,...
                         app.Rule2TwoOutsideWL.Value,...
                         app.Rule3Trend7points.Value,...
                         app.Rule4Bias8points.Value];
                %
                if isempty(app.reference_data)
                    data = app.sensor_data;
                else
                    data = app.reference_data-app.sensor_data;
                end
                
                if strcmpi(app.calibration_mode,'auto')
                    app.NumbercalibrationpointsEditField.Value = ...
                        max(app.NumbercalibrationpointsEditField.Value,2);
                end                     
                     
                % Plot Control Chart
                if strcmpi(app.CharttypeDropDown.Value, 'shewhart')
            
            
                    app.StabilityAnalysisRulesPanel.Visible=true;
                    app.TargetValueLabel.Text='Target Value (Center line)';
                    app.DeviationEditFieldLabel.Text = 'Deviation (s.d)';
                    app.ControlLimitEditFieldLabel.Text = 'Control Limit';
                    app.WarningLimitEditFieldLabel.Visible = true;
                    app.WarningLimitEditField.Visible = true;
                    app.ShowVmaskatSpinner.Visible = false;
                    app.ShowVmaskatSpinnerLabel.Visible = false;
                    
                    %% Plot Shewhart Control Chart
                    if isempty(app.reference_data)
                        ylab = 'Data';
                    else
                        ylab = 'Difference';
                    end
                    if strcmpi(app.calibration_mode,'auto')
                        
                        % Automatically defines limits based on mean and
                        % standard deviation on the calibration points.
                        [mu, sd, sig_limits,within_rules] = ct_shewhart(data,...
                            app.NumbercalibrationpointsEditField.Value, apply_rules,...
                            fig_handle,ylab);
                        
                        app.WarningLimitEditField.Value = sig_limits(2);
                        app.ControlLimitEditField.Value = sig_limits(3);

                    elseif strcmpi(app.calibration_mode,'manual')
                        % Uses user defined calibration values
                        [mu,sd,~,within_rules] = ct_shewhart(data,...
                            app.NumbercalibrationpointsEditField.Value, apply_rules,...
                            fig_handle, ylab, ...
                            app.TargetValueEditField.Value,...
                            app.DeviationEditField.Value,...
                            [1, app.WarningLimitEditField.Value, app.ControlLimitEditField.Value]);
                    end
                    app.TargetValueEditField.Value = mu;
                    app.DeviationEditField.Value = sd;
                    
                elseif strcmpi(app.CharttypeDropDown.Value, 'cusum')
                    app.StabilityAnalysisRulesPanel.Visible=false;
                    app.TargetValueLabel.Text='Target Value';
                    app.DeviationEditFieldLabel.Text = 'k';
                    app.ControlLimitEditFieldLabel.Text = 'h';
                    app.WarningLimitEditFieldLabel.Visible = false;
                    app.WarningLimitEditField.Visible = false;
                    app.ShowVmaskatSpinner.Visible = true;
                    app.ShowVmaskatSpinnerLabel.Visible = true;
                    
                    %% Plot CUSUM Control Chart
                    if strcmpi(app.calibration_mode,'auto')
                        
                        app.ControlLimitEditField.Value = 4; % h
                        app.DeviationEditField.Value = 0.5; % k
                        
                        % Automatically define target
                        mu_target = [];
                        
                    elseif strcmpi(app.calibration_mode,'manual')
                        % Uses user defined calibration values
                        mu_target = app.TargetValueEditField.Value;
                    end
                    cusum_param = [app.ControlLimitEditField.Value,... % h
                                       app.DeviationEditField.Value ]; % k
                    t_vmasks = app.ShowVmaskatSpinner.Value;
                    if t_vmasks == 0
                        t_vmasks=[];
                    end
                    
                    [mu_target, within_rules] = ct_cusum(1:length(app.sensor_data),...
                        data, app.NumbercalibrationpointsEditField.Value,...
                        mu_target, cusum_param,t_vmasks,fig_handle);
                    
                    app.TargetValueEditField.Value=mu_target;
                    within_rules = repmat(within_rules,4,1);
                    apply_rules(1)=true;
%                     within_rules = false(4,1); % This needs to be defined for controlling the lamp color
                    
                else
                    error('Unknown control chart type "%s" ! ', app.CharttypeDropDown.Value);
                    
                end
                
                
                % Changes text color of the stability rules
                app.updateStabilityRules(within_rules)
                
                % Changes the color of the in-control lamp. Only check the
                % checked rules even if others are broken.
                if any(apply_rules)
                    if all(within_rules(apply_rules))
                        app.ProcessincontrolLamp.Color = app.green;
                    else
                        app.ProcessincontrolLamp.Color = app.red;
                    end
                    
                else
                    app.ProcessincontrolLamp.Color = app.basecolor;
                end
                
                figure(app.UIFigure);
            end
            
            
        end
        
        %% Color the text of the stability rules if they are within or outside spec.
        function updateStabilityRules(app, abides_by_rule)
            if app.Rule1OutsideControl.Value
                if abides_by_rule(1)
                    app.Rule1OutsideControl.FontColor = app.green;
                else
                    app.Rule1OutsideControl.FontColor = app.red;
                end
            else
                app.Rule1OutsideControl.FontColor = [0,0,0];
            end
            
            if app.Rule2TwoOutsideWL.Value
                if abides_by_rule(2)
                    app.Rule2TwoOutsideWL.FontColor = app.green;
                else
                    app.Rule2TwoOutsideWL.FontColor = app.red;
                end
            else
                app.Rule2TwoOutsideWL.FontColor = [0,0,0];
            end
            
            if app.Rule3Trend7points.Value
                if abides_by_rule(3)
                    app.Rule3Trend7points.FontColor = app.green;
                else
                    app.Rule3Trend7points.FontColor = app.red;
                end
            else
                app.Rule3Trend7points.FontColor = [0,0,0];
            end
            
            if app.Rule4Bias8points.Value
                if abides_by_rule(4)
                    app.Rule4Bias8points.FontColor = app.green;
                else
                    app.Rule4Bias8points.FontColor = app.red;
                end
            else
                app.Rule4Bias8points.FontColor = [0,0,0];
            end
            
        
        end
    
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.CalibrationPanel.Visible = false;
            app.StabilityAnalysisRulesPanel.Visible = false;
            app.UIFigure.Name = 'Control Chart App (v1.0.0, October 2023) - Check for updates at https://github.com/JesperLH/ControlChartApp';

        end

        % Button pushed function: GenerateDataButton
        function GenerateDataButtonPushed(app, event)
            getSimulatedData(app,app.number_of_samples.Value, ...
                app.target.Value, app.target_sd.Value,...
                app.ReferenceVariationEditField.Value, app.SensorVariationEditField.Value,...
                app.OutliersEditField.Value, app.IncalibrationCheckBox.Value)
            
            app.NumbercalibrationpointsEditField.Limits=[0,app.number_of_samples.Value];
            app.ShowVmaskatSpinner.Limits = [0, length(app.sensor_data)];
            app.ShowVmaskatSpinner.Value = length(app.sensor_data);
            
            app.isSimulatedDataGenerated.Color = app.green;
            app.isRealDataLoaded.Color = app.red;
            app.data_type = 'simulated';
            
            % Update plot...
            app.updateFigure()
        end

        % Callback function
        function SaveFigurepngButtonPushed(app, event)
           screen_pos = get(0,'ScreenSize');
            % Get filename and location
            [file,name,~] = uiputfile('*.png','File Selection','.png');
            if length(file)>1 || file ~=0
                fileName = strcat(name,file);
    %             get(app.UIFigure);
                
                app.saveCurrentAppView(fileName, screen_pos);
            end
        end

        % Value changed function: target
        function targetValueChanged(app, event)
            app.simulatedDataChanged();
        end

        % Value changed function: target_sd
        function target_sdValueChanged(app, event)
            app.simulatedDataChanged();
            
        end

        % Value changed function: number_of_samples
        function number_of_samplesValueChanged(app, event)
            app.simulatedDataChanged();
            
        end

        % Value changed function: ReferenceVariationEditField
        function ReferenceVariationEditFieldValueChanged(app, event)
            app.simulatedDataChanged();
            
        end

        % Value changed function: SensorVariationEditField
        function SensorVariationEditFieldValueChanged(app, event)
            app.simulatedDataChanged();
            
        end

        % Value changed function: OutliersEditField
        function OutliersEditFieldValueChanged(app, event)
            app.simulatedDataChanged();
            
        end

        % Value changed function: IncalibrationCheckBox
        function IncalibrationCheckBoxValueChanged(app, event)
            app.simulatedDataChanged();
            
        end

        % Value changed function: NumbercalibrationpointsEditField
        function NumbercalibrationpointsEditFieldValueChanged(app, event)
            app.controlChartChanged(app.bottomplot)
            
        end

        % Button pushed function: LoadDataButton
        function LoadDataButtonPushed(app, event)
            [file,path] = uigetfile({'*.xlsx;*.xls', 'Excel files';'*.*','All Files'},'Load data from .xls or .xlsx files');
            
            if length(file)>1 || file ~=0
                hwait = uiprogressdlg(app.UIFigure,"Title",'Loading data','Message','Data is being loaded from excel file, please wait.');
                data = xlsread(strcat(path,file));
                
                if size(data,2)==1
                    app.sensor_data = data(:,1);
                    app.reference_data = [];
                else
                    app.reference_data = data(:,1);
                    app.sensor_data = data(:,2);
                end
                    
                
                app.number_of_samples.Value = size(data,1);
                app.NumbercalibrationpointsEditField.Limits=[0,app.number_of_samples.Value];
                app.NumbercalibrationpointsEditField.Value = round(size(data,1)*0.4);               
                app.ShowVmaskatSpinner.Limits = [0, length(app.sensor_data)];
                app.ShowVmaskatSpinner.Value = length(app.sensor_data);
                
                app.isSimulatedDataGenerated.Color = app.red;
                app.isRealDataLoaded.Color = app.green;
                app.data_type = 'real';
                
                hwait.Value=0.5;
                hwait.Message='Updating user interface';
                
                % Update figures
                app.data_changed();
                app.updateFigure();
            end
            figure(app.UIFigure)
            hwait.close;
            
        end

        % Button pushed function: EditDataButton
        function EditDataButtonPushed(app, event)
            if isempty(app.data_type)
                warning('No data was present!! Try loading or simulating data first.');
            
            else
                screen_pos = get(0,'ScreenSize');
                fig = figure('Position',floor([screen_pos(3) screen_pos(4), screen_pos(3) , screen_pos(4)]./[1.5,2,4,2.5]));
                if ~isempty(app.reference_data)
                    [new_ref, new_sensor] = edit_data(app.reference_data, app.sensor_data, fig);
                    %waitfor(fig)
                    % TODO: Ask to save/use this
                    app.reference_data = new_ref;
                    app.sensor_data = new_sensor;
                else
                    [~,new_sensor] = edit_data([],app.sensor_data, fig);
                    %waitfor(fig)
                    % TODO: Ask to save/use this
                    app.sensor_data = new_sensor;
                end
                if strcmpi(app.data_type, 'simulated')
                    app.isSimulatedDataGenerated.Color = app.purple;
                    
                elseif strcmpi(app.data_type, 'real')
                    app.isRealDataLoaded.Color = app.purple;
                    
                end

                app.updateFigure();
                
            end
            
        end

        % Value changed function: AutomaticButton
        function AutomaticButtonValueChanged(app, event)
            app.calibration_mode = 'auto';
            app.AutomaticButton.Value = true;
            app.ManualButton.Value = false;
            app.controlChartChanged(app.bottomplot);
            
        end

        % Value changed function: ManualButton
        function ManualButtonValueChanged(app, event)
            app.AutomaticButton.Value = false;
            app.ManualButton.Value = true;
            app.calibration_mode = 'manual';
            
            app.controlChartChanged(app.bottomplot);
            
        end

        % Value changed function: TargetValueEditField
        function TargetValueEditFieldValueChanged(app, event)
            app.ManualButtonValueChanged()
            app.controlChartChanged(app.bottomplot)
            
        end

        % Value changed function: DeviationEditField
        function DeviationEditFieldValueChanged(app, event)
            app.ManualButtonValueChanged()
            app.controlChartChanged(app.bottomplot)
            
        end

        % Value changed function: ControlLimitEditField
        function ControlLimitEditFieldValueChanged(app, event)
            app.ControlLimitEditField.Limits = [app.WarningLimitEditField.Value, inf];
            if app.ControlLimitEditField.Value < app.WarningLimitEditField.Value
                app.ControlLimitEditField.Value = app.WarningLimitEditField.Value*1.1;
            end
            app.ManualButtonValueChanged()
            app.controlChartChanged(app.bottomplot)
            
            
        end

        % Value changed function: WarningLimitEditField
        function WarningLimitEditFieldValueChanged(app, event)
            app.WarningLimitEditField.Limits = [1, inf];
            if app.WarningLimitEditField.Value < 1
                app.WarningLimitEditField.Value = 1.1;
            end
            app.ManualButtonValueChanged()
            app.controlChartChanged(app.bottomplot)

            
        end

        % Value changed function: Rule1OutsideControl
        function Rule1OutsideControlValueChanged(app, event)
            app.controlChartChanged(app.bottomplot);
            
        end

        % Value changed function: Rule2TwoOutsideWL
        function Rule2TwoOutsideWLValueChanged(app, event)
            app.controlChartChanged(app.bottomplot);
            
        end

        % Value changed function: Rule3Trend7points
        function Rule3Trend7pointsValueChanged(app, event)
            app.controlChartChanged(app.bottomplot);
            
        end

        % Value changed function: Rule4Bias8points
        function Rule4Bias8pointsValueChanged(app, event)
            app.controlChartChanged(app.bottomplot);
            
        end

        % Value changed function: CharttypeDropDown
        function CharttypeDropDownValueChanged(app, event)
            app.calibration_mode = 'auto'; %
            app.AutomaticButton.Value = true;
            app.ManualButton.Value=false;
            app.controlChartChanged(app.bottomplot);
        end

        % Callback function
        function VmaskSliderValueChanged(app, event)
            app.controlChartChanged(app.bottomplot)
            
        end

        % Value changed function: ShowVmaskatSpinner
        function ShowVmaskatSpinnerValueChanged(app, event)
            
            app.controlChartChanged(app.bottomplot)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.bottomplot = uiaxes(app.UIFigure);
            title(app.bottomplot, 'Control Chart (Shewhart)')
            xlabel(app.bottomplot, 'Observations')
            ylabel(app.bottomplot, 'Difference')
            app.topplot = uiaxes(app.UIFigure);
            title(app.topplot, 'Reference and Observed Data')
            xlabel(app.topplot, 'Observations')
            ylabel(app.topplot, 'Value')
            app.SimulateDataPanel = uipanel(app.UIFigure);
            app.SimulateDataPanel.Title = 'Simulate Data';
            app.ReferenceVariationEditFieldLabel = uilabel(app.SimulateDataPanel);
            app.ReferenceVariationEditFieldLabel.Text = 'Reference Variation';
            app.ReferenceVariationEditField = uieditfield(app.SimulateDataPanel, 'numeric');
            app.ReferenceVariationEditField.Limits = [0 Inf];
            app.ReferenceVariationEditField.ValueChangedFcn = createCallbackFcn(app, @ReferenceVariationEditFieldValueChanged, true);
            app.ReferenceVariationEditField.Value = 0.5;
            app.SensorVariationEditFieldLabel = uilabel(app.SimulateDataPanel);
            app.SensorVariationEditFieldLabel.Text = 'Sensor Variation';
            app.SensorVariationEditField = uieditfield(app.SimulateDataPanel, 'numeric');
            app.SensorVariationEditField.Limits = [0 Inf];
            app.SensorVariationEditField.ValueChangedFcn = createCallbackFcn(app, @SensorVariationEditFieldValueChanged, true);
            app.SensorVariationEditField.Value = 0.7;
            app.OutliersEditFieldLabel = uilabel(app.SimulateDataPanel);
            app.OutliersEditFieldLabel.Text = 'Outliers';
            app.OutliersEditField = uieditfield(app.SimulateDataPanel, 'numeric');
            app.OutliersEditField.Limits = [0 Inf];
            app.OutliersEditField.RoundFractionalValues = 'on';
            app.OutliersEditField.ValueChangedFcn = createCallbackFcn(app, @OutliersEditFieldValueChanged, true);
            app.IncalibrationCheckBox = uicheckbox(app.SimulateDataPanel);
            app.IncalibrationCheckBox.ValueChangedFcn = createCallbackFcn(app, @IncalibrationCheckBoxValueChanged, true);
            app.IncalibrationCheckBox.Text = 'In calibration';
            app.isSimulatedDataGenerated = uilamp(app.SimulateDataPanel);
            app.GenerateDataButton = uibutton(app.SimulateDataPanel, 'push');
            app.GenerateDataButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateDataButtonPushed, true);
            app.GenerateDataButton.Text = 'Generate Data';
            app.HowmanycalibrationpointsEditFieldLabel_2 = uilabel(app.SimulateDataPanel);
            app.HowmanycalibrationpointsEditFieldLabel_2.Text = 'How many data points';
            app.number_of_samples = uieditfield(app.SimulateDataPanel, 'numeric');
            app.number_of_samples.Limits = [2 5000];
            app.number_of_samples.RoundFractionalValues = 'on';
            app.number_of_samples.ValueDisplayFormat = '%.0f';
            app.number_of_samples.ValueChangedFcn = createCallbackFcn(app, @number_of_samplesValueChanged, true);
            app.number_of_samples.Value = 99;
            app.HowmanycalibrationpointsEditFieldLabel_3 = uilabel(app.SimulateDataPanel);
            app.HowmanycalibrationpointsEditFieldLabel_3.Text = 'Target Value and SD';
            app.target = uieditfield(app.SimulateDataPanel, 'numeric');
            app.target.ValueDisplayFormat = '%.0f';
            app.target.ValueChangedFcn = createCallbackFcn(app, @targetValueChanged, true);
            app.target.Value = 5;
            app.target_sd = uieditfield(app.SimulateDataPanel, 'numeric');
            app.target_sd.Limits = [0 5000];
            app.target_sd.ValueChangedFcn = createCallbackFcn(app, @target_sdValueChanged, true);
            app.target_sd.Value = 0.1;
            app.StabilityAnalysisRulesPanel = uipanel(app.UIFigure);
            app.StabilityAnalysisRulesPanel.Title = 'Stability Analysis Rules';
            app.Rule1OutsideControl = uicheckbox(app.StabilityAnalysisRulesPanel);
            app.Rule1OutsideControl.ValueChangedFcn = createCallbackFcn(app, @Rule1OutsideControlValueChanged, true);
            app.Rule1OutsideControl.Text = 'Rule 1: Outside control limit';
            app.Rule2TwoOutsideWL = uicheckbox(app.StabilityAnalysisRulesPanel);
            app.Rule2TwoOutsideWL.ValueChangedFcn = createCallbackFcn(app, @Rule2TwoOutsideWLValueChanged, true);
            app.Rule2TwoOutsideWL.Text = 'Rule 2: Two in a row outside warning limit';
            app.Rule3Trend7points = uicheckbox(app.StabilityAnalysisRulesPanel);
            app.Rule3Trend7points.ValueChangedFcn = createCallbackFcn(app, @Rule3Trend7pointsValueChanged, true);
            app.Rule3Trend7points.Text = 'Rule 3: Trend (7 increasing or decreasing)';
            app.Rule4Bias8points = uicheckbox(app.StabilityAnalysisRulesPanel);
            app.Rule4Bias8points.ValueChangedFcn = createCallbackFcn(app, @Rule4Bias8pointsValueChanged, true);
            app.Rule4Bias8points.Text = 'Rule 4: Bias (8 points on the same side)';
            app.CalibrationPanel = uipanel(app.UIFigure);
            app.CalibrationPanel.Title = 'Calibration';
            app.NumbercalibrationpointsEditFieldLabel = uilabel(app.CalibrationPanel);
            app.NumbercalibrationpointsEditFieldLabel.Text = 'Number calibration points?';
            app.NumbercalibrationpointsEditField = uieditfield(app.CalibrationPanel, 'numeric');
            app.NumbercalibrationpointsEditField.Limits = [0 Inf];
            app.NumbercalibrationpointsEditField.RoundFractionalValues = 'on';
            app.NumbercalibrationpointsEditField.ValueDisplayFormat = '%.0f';
            app.NumbercalibrationpointsEditField.ValueChangedFcn = createCallbackFcn(app, @NumbercalibrationpointsEditFieldValueChanged, true);
            app.NumbercalibrationpointsEditField.Value = 80;
            app.TargetValueLabel = uilabel(app.CalibrationPanel);
            app.TargetValueLabel.Text = 'Target Value (Center Line)';
            app.TargetValueEditField = uieditfield(app.CalibrationPanel, 'numeric');
            app.TargetValueEditField.ValueChangedFcn = createCallbackFcn(app, @TargetValueEditFieldValueChanged, true);
            app.DeviationEditFieldLabel = uilabel(app.CalibrationPanel);
            app.DeviationEditFieldLabel.Text = 'Deviation';
            app.DeviationEditField = uieditfield(app.CalibrationPanel, 'numeric');
            app.DeviationEditField.Limits = [0 Inf];
            app.DeviationEditField.ValueChangedFcn = createCallbackFcn(app, @DeviationEditFieldValueChanged, true);
            app.ControlLimitEditFieldLabel = uilabel(app.CalibrationPanel);
            app.ControlLimitEditFieldLabel.Text = 'Control Limit';
            app.ControlLimitEditField = uieditfield(app.CalibrationPanel, 'numeric');
            app.ControlLimitEditField.Limits = [0 Inf];
            app.ControlLimitEditField.ValueChangedFcn = createCallbackFcn(app, @ControlLimitEditFieldValueChanged, true);
            app.ControlLimitEditField.Value = 3;
            app.WarningLimitEditFieldLabel = uilabel(app.CalibrationPanel);
            app.WarningLimitEditFieldLabel.Text = 'Warning Limit';
            app.AutomaticButton = uibutton(app.CalibrationPanel, 'state');
            app.AutomaticButton.ValueChangedFcn = createCallbackFcn(app, @AutomaticButtonValueChanged, true);
            app.AutomaticButton.Text = 'Automatic';
            app.AutomaticButton.Value = true;
            app.ManualButton = uibutton(app.CalibrationPanel, 'state');
            app.ManualButton.ValueChangedFcn = createCallbackFcn(app, @ManualButtonValueChanged, true);
            app.ManualButton.Text = 'Manual';
            app.WarningLimitEditField = uieditfield(app.CalibrationPanel, 'numeric');
            app.WarningLimitEditField.Limits = [0 Inf];
            app.WarningLimitEditField.ValueChangedFcn = createCallbackFcn(app, @WarningLimitEditFieldValueChanged, true);
            app.WarningLimitEditField.Value = 2;
            app.ShowVmaskatSpinnerLabel = uilabel(app.CalibrationPanel);
            app.ShowVmaskatSpinnerLabel.Text = 'Show V-mask at';
            app.ShowVmaskatSpinner = uispinner(app.CalibrationPanel);
            app.ShowVmaskatSpinner.Limits = [0 Inf];
            app.ShowVmaskatSpinner.ValueChangedFcn = createCallbackFcn(app, @ShowVmaskatSpinnerValueChanged, true);
            app.ShowVmaskatSpinner.Value = 1;
            app.ManageDataPanel = uipanel(app.UIFigure);
            app.ManageDataPanel.Title = 'Manage Data';
            app.LoadDataButton = uibutton(app.ManageDataPanel, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Text = 'Load Data';
            app.EditDataButton = uibutton(app.ManageDataPanel, 'push');
            app.EditDataButton.ButtonPushedFcn = createCallbackFcn(app, @EditDataButtonPushed, true);
            app.EditDataButton.Text = 'Edit Data';
            app.isRealDataLoaded = uilamp(app.ManageDataPanel);
            app.ProcessincontrolLampLabel = uilabel(app.UIFigure);
            app.ProcessincontrolLampLabel.Text = 'Process in control?';
            app.ProcessincontrolLamp = uilamp(app.UIFigure);
            app.CharttypeDropDownLabel = uilabel(app.UIFigure);
            app.CharttypeDropDownLabel.Text = 'Chart type';
            app.CharttypeDropDown = uidropdown(app.UIFigure);
            app.CharttypeDropDown.Items = {'Shewhart', 'CUSUM'};
            app.CharttypeDropDown.ValueChangedFcn = createCallbackFcn(app, @CharttypeDropDownValueChanged, true);
            app.CharttypeDropDown.Value = 'Shewhart';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ControlChart

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end